classdef PIETOOLS_2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIETOOLS2021aUIFigure       matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        LHSDropDownLabel            matlab.ui.control.Label
        LHSDropDown                 matlab.ui.control.DropDown
        MultiplierEditField         matlab.ui.control.EditField
        DropDown                    matlab.ui.control.DropDown
        DropDown_2                  matlab.ui.control.DropDown
        aEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.EditField
        bEditFieldLabel             matlab.ui.control.Label
        bEditField                  matlab.ui.control.EditField
        AddtoDynamicsButton         matlab.ui.control.Button
        DropDown_3                  matlab.ui.control.DropDown
        AddnewBCButton              matlab.ui.control.Button
        DropDown_4                  matlab.ui.control.DropDown
        AddtoBCButton               matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        TextArea_2                  matlab.ui.control.TextArea
        RightPanel                  matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        VariableNameEditFieldLabel  matlab.ui.control.Label
        VariableNameEditField       matlab.ui.control.EditField
        AddPDEstateButton           matlab.ui.control.Button
        AddODEstateButton           matlab.ui.control.Button
        UITable_2                   matlab.ui.control.Table
        UITable_3                   matlab.ui.control.Table
        AddInputButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeGUI(app)
            set(app.LHSDropDown,'Items',{});
            set(app.DropDown,'Items',{});
        end

        % Button pushed function: AddPDEstateButton
        function addstatePDE(app, event)
            % need to check if the variable already exists and then add
            
            state_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            if ~any(strcmp(get(app.UITable,'Data'),state_name)) && ...
                    ~any(strcmp(get(app.UITable_2,'Data'),state_name)) && ...
                    ~any(strcmp(get(app.UITable_3,'Data'),state_name))
                tabledata = get(app.UITable_2, 'Data');
                tabledata = [tabledata; [state_name 0]];
                set(app.UITable_2, 'Data', tabledata);
            
            % add state to dynamics
            state_name_t = convertStringsToChars(strcat(state_name,"_t"));
            dynamics_list = get(app.LHSDropDown,'Items');
            dynamics_list{end+1} = state_name_t;
            set(app.LHSDropDown,'Items', dynamics_list);
            
            textDyn = get(app.TextArea,'Value');
            textDyn{end+1} = [state_name_t,' = 0'];
            set(app.TextArea,'Value',textDyn);
            
            RHS_terms_list = get(app.DropDown, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.DropDown,'Items', RHS_terms_list);
            end
        end

        % Button pushed function: AddODEstateButton
        function addstateODE(app, event)
            % need to check if the variable already exists and then add
            state_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            if ~any(strcmp(get(app.UITable,'Data'),state_name)) && ...
                    ~any(strcmp(get(app.UITable_2,'Data'),state_name)) && ...
                    ~any(strcmp(get(app.UITable_3,'Data'),state_name))
                tabledata = get(app.UITable, 'Data');
                tabledata = [tabledata; state_name];
                set(app.UITable, 'Data', tabledata);
            
            % add state to dynamics
            state_name_t = convertStringsToChars(strcat(state_name,"_t"));
            dynamics_list = get(app.LHSDropDown,'Items');
            
            dynamics_list{end+1} = state_name_t;
            set(app.LHSDropDown,'Items', dynamics_list);
            
            textDyn = get(app.TextArea,'Value');
            textDyn{end+1} = [state_name_t,' = 0'];
            set(app.TextArea,'Value',textDyn);
            
            RHS_terms_list = get(app.DropDown, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.DropDown,'Items', RHS_terms_list);
            end
            
        end

        % Button pushed function: AddInputButton
        function addInput(app, event)
            % need to check if the variable already exists and then add
            input_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            if ~any(strcmp(get(app.UITable,'Data'),input_name)) && ...
                    ~any(strcmp(get(app.UITable_2,'Data'),input_name)) && ...
                    ~any(strcmp(get(app.UITable_3,'Data'),input_name))
            tabledata = get(app.UITable_3, 'Data');
            tabledata = [tabledata; input_name];
            set(app.UITable_3, 'Data', tabledata);
            
            RHS_terms_list = get(app.DropDown, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.DropDown,'Items', RHS_terms_list);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PIETOOLS2021aUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x', 218};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: AddtoDynamicsButton
        function addTerms2Dynamics(app, event)
            a = get(app.aEditField, 'Value');
            b = get(app.bEditField, 'Value');
            termType = get(app.DropDown_2,'Value');
            coeff = get(app.MultiplierEditField,'Value');
            stateterm = get(app.DropDown,'Value');
            LHS = get(app.LHSDropDown,'Value');
            
            textDyn = get(app.TextArea,'Value');
            idx = find(contains(textDyn,LHS));
            existingTxt = textDyn{idx};
            
            if strcmp(termType,'Multiplier')
                replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm));
                textDyn{idx} = replaceTxt;
                set(app.TextArea,'Value',textDyn);
            else
                replaceTxt = strcat(existingTxt,['+int((',coeff,')*',stateterm,...
                    ',',get(app.DropDown_3,'Value'),',',get(app.aEditField, 'Value'),...
                    ',',get(app.bEditField, 'Value'),')']);
                textDyn{idx} = replaceTxt;
                set(app.TextArea,'Value',textDyn);
            end
            
        end

        % Cell edit callback: UITable_2
        function changeDerivative(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            tabledata = get(app.UITable_2,'Data');
            changedState = convertStringsToChars(tabledata(indices(1),1));
            list = get(app.DropDown,'Items');
            
            if str2double(event.NewData)>str2double(event.PreviousData)
                for i=str2double(event.PreviousData)+1:str2double(event.NewData)
                    list{end+1} = [changedState,'_',repmat('s',1,i)];
                    j = i-1;
                    list{end+1} = [changedState,'_',repmat('s',1,j),'(0)'];
                    list{end+1} = [changedState,'_',repmat('s',1,j),'(1)'];
                end
            elseif str2double(event.NewData)<str2double(event.PreviousData)
                for i=str2double(event.NewData)+1:str2double(event.PreviousData)
                    list(strcmp(list,[changedState,'_',repmat('s',1,i)])) = [];
                    j = i-1;
                    list(strcmp(list,[changedState,'_',repmat('s',1,j),'(0)'])) = [];
                    list(strcmp(list,[changedState,'_',repmat('s',1,j),'(1)'])) = [];
                end
            end
            set(app.DropDown,'Items',list);
        end

        % Button pushed function: AddtoBCButton
        function addTerm2BC(app, event)
            a = get(app.aEditField, 'Value');
            b = get(app.bEditField, 'Value');
            termType = get(app.DropDown_2,'Value');
            coeff = get(app.MultiplierEditField,'Value');
            stateterm = get(app.DropDown,'Value');
            
            textDyn = get(app.TextArea_2,'Value');
            existingTxt = textDyn{str2double(get(app.DropDown_4,'Value'))+1};
            
            if strcmp(termType,'Multiplier')
                replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm));
                textDyn{str2double(get(app.DropDown_4,'Value'))+1} = replaceTxt;
                set(app.TextArea_2,'Value',textDyn);
            else
                replaceTxt = strcat(existingTxt,['+int((',coeff,')*',stateterm,...
                    ',',get(app.DropDown_3,'Value'),',',get(app.aEditField, 'Value'),...
                    ',',get(app.bEditField, 'Value'),')']);
                textDyn{str2double(get(app.DropDown_4,'Value'))+1} = replaceTxt;
                set(app.TextArea_2,'Value',textDyn);
            end
        end

        % Button pushed function: AddnewBCButton
        function addBC(app, event)
            textBC = get(app.TextArea_2,'Value');
            textBC{end+1} = ['0 = 0'];
            BClist = get(app.DropDown_4,'Items');
            BClist{end+1} = num2str(length(BClist)+1);
            
            set(app.DropDown_4,'Items',BClist);
            set(app.TextArea_2,'Value',textBC);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIETOOLS2021aUIFigure = uifigure('Visible', 'off');
            app.PIETOOLS2021aUIFigure.Name = 'PIETOOLS 2021a';
            app.PIETOOLS2021aUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PIETOOLS2021aUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LHSDropDownLabel = uilabel(app.LeftPanel);
            app.LHSDropDownLabel.Text = 'LHS';
            app.LHSDropDown = uidropdown(app.LeftPanel);
            app.MultiplierEditField = uieditfield(app.LeftPanel, 'text');
            app.MultiplierEditField.Tag = 'Coefficient';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Tag = 'Int_Mul_state';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'Multiplier', 'Integral', ''};
            app.DropDown_2.Value = 'Multiplier';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'text');
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'text');
            app.AddtoDynamicsButton = uibutton(app.LeftPanel, 'push');
            app.AddtoDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @addTerms2Dynamics, true);
            app.AddtoDynamicsButton.Tag = 'AddDynamicsTerms';
            app.AddtoDynamicsButton.Text = 'Add to Dynamics';
            app.DropDown_3 = uidropdown(app.LeftPanel);
            app.DropDown_3.Items = {'ds', 'dtheta'};
            app.DropDown_3.Tag = 'intVariable';
            app.DropDown_3.Value = 'ds';
            app.AddnewBCButton = uibutton(app.LeftPanel, 'push');
            app.AddnewBCButton.ButtonPushedFcn = createCallbackFcn(app, @addBC, true);
            app.AddnewBCButton.Tag = 'AddDynamicsTerms';
            app.AddnewBCButton.Text = 'Add new BC';
            app.DropDown_4 = uidropdown(app.LeftPanel);
            app.DropDown_4.Items = {};
            app.DropDown_4.Value = {};
            app.AddtoBCButton = uibutton(app.LeftPanel, 'push');
            app.AddtoBCButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2BC, true);
            app.AddtoBCButton.Tag = 'AddDynamicsTerms';
            app.AddtoBCButton.Text = 'Add to BC';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.CenterPanel);
            app.TextArea.Tag = 'Dynamics';
            app.TextArea_2 = uitextarea(app.CenterPanel);
            app.TextArea_2.Tag = 'BCs';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'ODE States'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'ODElist';
            app.VariableNameEditFieldLabel = uilabel(app.RightPanel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.RightPanel, 'text');
            app.AddPDEstateButton = uibutton(app.RightPanel, 'push');
            app.AddPDEstateButton.ButtonPushedFcn = createCallbackFcn(app, @addstatePDE, true);
            app.AddPDEstateButton.Tag = 'buttonAddPDE';
            app.AddPDEstateButton.Text = 'Add PDE state';
            app.AddODEstateButton = uibutton(app.RightPanel, 'push');
            app.AddODEstateButton.ButtonPushedFcn = createCallbackFcn(app, @addstateODE, true);
            app.AddODEstateButton.Tag = 'buttonAddODE';
            app.AddODEstateButton.Text = 'Add ODE state';
            app.UITable_2 = uitable(app.RightPanel);
            app.UITable_2.ColumnName = {'PDE States'; 'Highest Derivative'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false true];
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @changeDerivative, true);
            app.UITable_2.Tag = 'PDElist';
            app.UITable_3 = uitable(app.RightPanel);
            app.UITable_3.ColumnName = {'Inputs'};
            app.UITable_3.RowName = {};
            app.UITable_3.Tag = 'Inputlist';
            app.AddInputButton = uibutton(app.RightPanel, 'push');
            app.AddInputButton.ButtonPushedFcn = createCallbackFcn(app, @addInput, true);
            app.AddInputButton.Tag = 'buttonAddInput';
            app.AddInputButton.Text = 'Add Input';
            % Show the figure after all components are created
            app.PIETOOLS2021aUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIETOOLS_2021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIETOOLS2021aUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIETOOLS2021aUIFigure)
        end
    end
end